classdef ESEapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EigenvaluesY2n1XY2m1Label  matlab.ui.control.Label
        LabelY                     matlab.ui.control.Label
        LabelX                     matlab.ui.control.Label
        nSlider                    matlab.ui.control.Slider
        nSliderLabel               matlab.ui.control.Label
        mSlider                    matlab.ui.control.Slider
        xLabel                     matlab.ui.control.Label
        MainAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Xvalue = 0 
        Yvalue = 0 
    end

    methods (Access = private)

        function CalcEngine(app)
            %% Define initial params
            y = (2 * round(app.nSlider.Value)) -1;
            x = y + ((2 * round(app.mSlider.Value)) -1); 
            app.LabelX.Text = sprintf('X = %d', x);
            app.LabelY.Text = sprintf('Y = %d', y);
            N = 5;   % Matrix dimension
            T0 = pi; % Initial PST
            %% Jacobi Matrix Setup
            J = Jacobi_5x5_Matrix(x,y,0);
            disp(J)
            %% Inner Product <e^(-iJt)e0, e0> setup
            e0 = zeros(N,1);
            e0(1) = 1; % First element of standard basis
            e4 = zeros(N,1);
            e4(5)=1;
            % ESE/PST Time intervals
            t = linspace(0,10,2000);
            func = zeros(size(t));
            func2 = func;
            for k = 1:length(t)
                E = expm(-1i*J*t(k));
                func(k) = e0'*E*e0;
                func2(k) = e4'*E*e0;
            end
            %% Plotting
            plot(app.MainAxes,t,abs(func), t, abs(func2), 'LineWidth', 0.5);
            legend(app.MainAxes,{'ESE', 'PST'}) 
            % plot(t,abs(func2), 'LineStyle', '--')
            xlabel(app.MainAxes,'t')
            ylabel(app.MainAxes,'Amplitude')
            title(app.MainAxes, 'Time Evolution of Quantum States')
            % grid on; 
            function J = Jacobi_5x5_Matrix(x,y,c)
                a = c*ones(5,1); % Center Diagonal
                b = (sqrt((x^2-y^2)/2)); % Center inputs of b0
                b0 = [y,b,b,y]; % Upper and Lower Diagonal 

                J = diag(a)+diag(b0,1)+diag(b0,-1);
            end
            % Rules for finding points of ESE and PST
            ESE = abs(func);
            PST = abs(func2);
            
            tol = 1e-2;
            
            idx_ESE = ESE < tol;
            [~, idx_ESE] = findpeaks(-ESE);
            idx_ESE = idx_ESE(ESE(idx_ESE) < tol);
            
            m = t(idx_ESE) < T0;
            idx_ESE = idx_ESE(m);
            t_ESE = t(idx_ESE);
            [~,idx_PST] = findpeaks(PST);
            idx_PST = idx_PST(abs(PST(idx_PST) - 1) < tol);
            if ~isempty(idx_PST)
                first_PST = idx_PST(1);
                t_PST = t(first_PST);
            else
                t_PST = NaN;
            end
            
            if ~isnan(t_PST)
                m = t(idx_ESE) < t_PST;
                idx_ESE = idx_ESE(m);
                t_ESE = t(idx_ESE);
            else
                m = t(idx_ESE) < T0;
                idx_ESE = idx_ESE(m);
                t_ESE = t(idx_ESE);
            end
            
            cla(app.MainAxes)
            hold(app.MainAxes, 'on')
            % Displays points of ESE only when x is multiple of y
            if mod(x,y) == 0
                plot(app.MainAxes, t, ESE, 'LineWidth', 0.5, ...
                'DisplayName','$|\langle e^{-iJt} \bf{e_0} , \bf{e_0} \rangle|$')
           plot(app.MainAxes, t, PST, 'LineWidth', 0.5, ...
                'Disp','$|(\langle e^{-iJt} \bf{e_0} , \bf{e_4} \rangle|$')
                if ~isnan(t_PST)
                    yl = ylim(app.MainAxes);
                    plot(app.MainAxes, [t_PST t_PST], yl, 'r--', 'LineWidth', 1.5, 'DisplayName', 'First Instance of PST')
                end
            else
            plot(app.MainAxes, t, ESE, 'LineWidth', 0.5, ...
                'DisplayName','$|\langle e^{-iJt} \bf{e_0} , \bf{e_0} \rangle|$')
           plot(app.MainAxes, t, PST, 'LineWidth', 0.5, ...
                'Disp','$|(\langle e^{-iJt} \bf{e_0} , \bf{e_4} \rangle|$')
                if ~isnan(t_PST)
            plot(app.MainAxes, t_ESE, ESE(idx_ESE), 'bo', 'LineWidth', 1.2, 'DisplayName', 'ESE before first PST')
                end
                if ~isnan(t_PST)
                    yl = ylim(app.MainAxes);
                    plot(app.MainAxes, [t_PST t_PST], yl, 'r--', 'LineWidth', 1.5, 'DisplayName', 'First Instance of PST')
                end
            end
            legend(app.MainAxes, 'Location', 'westoutside', 'Interpreter', 'latex', 'AutoUpdate', 'on')
            end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: nSlider
        function nSliderValueChanged(app, event)
            %app.Yvalue = app.nSlider.Value;
            %app.YvalueLabel.Text = sprintf("Y Value: %.3f", app.Yvalue);
            CalcEngine(app)
        end

        % Value changed function: mSlider
        function mSliderValueChanged(app, event)
            %app.Xvalue = app.mSlider.Value;
            %app.XvalueLabel.Text = sprintf("X Value: %.3f", app.Xvalue);
            CalcEngine(app)
        end

        % Callback function: not associated with a component
        function ArbitraryXYCheckBoxValueChanged(app, event)
            %value = app.ArbitraryXYCheckBox.Value;
            CalcEngine(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainAxes = uiaxes(app.UIFigure);
            title(app.MainAxes, 'Title')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            zlabel(app.MainAxes, 'Z')
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'm';
            app.mSlider = uislider(app.UIFigure);
            app.mSlider.Limits = [1 10];
            app.mSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.mSlider.ValueChangedFcn = createCallbackFcn(app, @mSliderValueChanged, true);
            app.mSlider.Step = 1;
            app.mSlider.Value = 1;
            app.nSliderLabel = uilabel(app.UIFigure);
            app.nSliderLabel.Text = 'n';
            app.nSlider = uislider(app.UIFigure);
            app.nSlider.Limits = [1 10];
            app.nSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.Step = 1;
            app.nSlider.Value = 1;
            app.LabelX = uilabel(app.UIFigure);
            app.LabelX.Text = 'X';
            app.LabelY = uilabel(app.UIFigure);
            app.LabelY.Text = 'Y';
            app.EigenvaluesY2n1XY2m1Label = uilabel(app.UIFigure);
            app.EigenvaluesY2n1XY2m1Label.Interpreter = 'latex';
            app.EigenvaluesY2n1XY2m1Label.Text = {'Eigenvalues: '; '$Y = 2n-1 $ & $X = Y + (2m-1) $'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESEapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end